classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotButton                      matlab.ui.control.Button
        ANALYSISOFSTOCHASTICPROCESSESLabel  matlab.ui.control.Label
        SamplefunctionstodisplayEditFieldLabel  matlab.ui.control.Label
        SamplefunctionstodisplayEditField  matlab.ui.control.NumericEditField
        CalculateandplottheensemblemeanButton  matlab.ui.control.Button
        CalculateandplotthestatisticalautocorrelationButton  matlab.ui.control.Button
        CalculatethetimeautocorrelationfunctionButton  matlab.ui.control.Button
        CalculateandplotthepowerspectraldensityButton  matlab.ui.control.Button
        CalculatethetotalaveragepowerButton  matlab.ui.control.Button
        ofsamplefunctionsiandjEditFieldLabel  matlab.ui.control.Label
        ofsamplefunctionsiandjEditField  matlab.ui.control.NumericEditField
        CalculatethetimemeanButton      matlab.ui.control.Button
        ofsamplefunctioniEditField_2    matlab.ui.control.NumericEditField
        ofsamplefunctionEditFieldLabel  matlab.ui.control.Label
        ofsamplefunctionEditField       matlab.ui.control.NumericEditField
        ofsamplefunctionEditField_2Label  matlab.ui.control.Label
        ofsamplefunctionEditField_2     matlab.ui.control.NumericEditField
        LoadButton                      matlab.ui.control.Button
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        t=zeros(1,101); % Description
        X=zeros(100,101);
        Mm=0;
        pTimeACF=zeros(1,101);
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            M=app.SamplefunctionstodisplayEditField.Value;
            app.Mm=M;
            [R C]=size(app.X);
            if M>0
                if M>R
                    str="The number entered exceeds the number of sample functions. The entire ensemble is plotted though";
                    f=msgbox(str,"Note",'none');
                    for i=1:R
                        figure;
                        plot(app.t,app.X(i,:));
                        title(['Sample Function ',num2str(i)]);
                        xlabel('Time (t)');
                        ylabel('Amplitude (X)');
                    end
                else
                    for i=1:M
                        figure;
                        plot(app.t,app.X(i,:));
                        title(['Sample Function ',num2str(i)]);
                        xlabel('Time (t)');
                        ylabel('Amplitude (X)');
                    end
                end
            elseif M<1
                f = msgbox("Error! Enter a value bigger than zero","Error",'error');
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            s=load(app.FilenameEditField.Value,'-mat','X','t');
            app.X=s.X;
            app.t=s.t;
        end

        % Value changed function: SamplefunctionstodisplayEditField
        function SamplefunctionstodisplayEditFieldValueChanged(app, event)
            value = app.SamplefunctionstodisplayEditField.Value;
            
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            value = app.FilenameEditField.Value;
            
        end

        % Button pushed function: 
        % CalculateandplottheensemblemeanButton
        function CalculateandplottheensemblemeanButtonPushed(app, event)
            enmean=mean(app.X);
            %figure;
            plot(app.UIAxes,app.t,enmean);
            title(app.UIAxes,'Ensemble Mean');
            xlabel(app.UIAxes,'Time (t)');
            ylabel(app.UIAxes,'Amplitude (X)');
            
            
        end

        % Value changed function: ofsamplefunctionEditField
        function ofsamplefunctionEditFieldValueChanged(app, event)
            value = app.ofsamplefunctionEditField.Value;
            
        end

        % Button pushed function: CalculatethetimemeanButton
        function CalculatethetimemeanButtonPushed(app, event)
            N=app.ofsamplefunctionEditField.Value;
            [R C]=size(app.X);
            if N<(R+1) &&N>0
                %fprintf('error, number must be less than %d \n',M);
                %N=app.ofsamplefunctionEditField.Value;
                
                diff=(app.t(C)-app.t(1))/length(app.t);
                tmean=mean(transpose(app.X(N,:)))/0.1;
                str = "Time Average of the Sample Function #  "+ N+"  is  " + tmean+ "    ";
                f = msgbox(str,"Time Mean",'none');
            elseif N<1
                f = msgbox("Error! Enter a number larger than zero","Error",'error');
                
            else
                f = msgbox("Error! Number is bigger than number of sample functions","Error",'error');
            end
        end

        % Value changed function: ofsamplefunctionsiandjEditField
        function ofsamplefunctionsiandjEditFieldValueChanged(app, event)
            value = app.ofsamplefunctionsiandjEditField.Value;
            
        end

        % Value changed function: ofsamplefunctioniEditField_2
        function ofsamplefunctioniEditField_2ValueChanged(app, event)
            value = app.ofsamplefunctioniEditField_2.Value;
            
        end

        % Button pushed function: 
        % CalculateandplotthestatisticalautocorrelationButton
        function CalculateandplotthestatisticalautocorrelationButtonPushed(app, event)
            i=app.ofsamplefunctionsiandjEditField.Value;
            j=app.ofsamplefunctioniEditField_2.Value;
            [R C]=size(app.X);
            if i<(C+1) &&j<(C+1) && i>0 &&j>0
                autoc=app.X(:,i).*app.X(:,j);
                autocmean=mean(autoc);
                %figure;
                str= "The Statistical Autocorrelation between SF # "+i+" and "+j+" is "+ autocmean;
                f = msgbox(str,"Rij",'none');
                plot(app.UIAxes,app.t,autocmean,".");
                title(app.UIAxes,'The Statistical Autocorrelation of The Random Process');
                xlabel(app.UIAxes,'');
                ylabel(app.UIAxes,'');
            else
                f = msgbox("Error! Wrong value for i or j","Error",'error');
            end
        end

        % Value changed function: ofsamplefunctionEditField_2
        function ofsamplefunctionEditField_2ValueChanged(app, event)
            value = app.ofsamplefunctionEditField_2.Value;
            
        end

        % Button pushed function: 
        % CalculatethetimeautocorrelationfunctionButton
        function CalculatethetimeautocorrelationfunctionButtonPushed(app, event)
            NN=app.ofsamplefunctionEditField_2.Value;
            multi=0;
            [R C]=size(app.X);
            if NN>0 && NN<R+1 
                for tao=0:C-1
                    for q=1:C
                        if (q+tao)<(C+1)
                            multi=multi+app.X(NN,q)*app.X(NN,q+tao);
                        end
                    end
                    TimeACF(tao+1)=multi;
                    multi=0;
                end
                app.pTimeACF=TimeACF;
                %figure;
                str= "The Time Autocorrelation between for SF # "+NN+" is ";
                f = msgbox(string(TimeACF),str,'none');
                %figure,plot(-(0:C),TimeACF,0:C,TimeACF);
                plot(app.UIAxes,-(0:C-1),TimeACF,0:C-1,TimeACF);
                title(app.UIAxes,'The Time Autocorrelation of The Random Process');
                xlabel(app.UIAxes,' Lag (tao)');
                ylabel(app.UIAxes,'Rx (tao)');
            elseif NN<1
                f = msgbox("Error! Enter a value bigger than zero","Error",'error');
                
            else
                f = msgbox("Error! Number is bigger than number of sample functions","Error",'error');
                
            end
            
            
        end

        % Button pushed function: 
        % CalculateandplotthepowerspectraldensityButton
        function CalculateandplotthepowerspectraldensityButtonPushed(app, event)
            [R C]=size(app.X);
            psd=abs(fft(app.pTimeACF,R)).^2/C;
            %figure,
            plot(app.UIAxes,psd);
            title(app.UIAxes,'The Power Spectral Density of The Random Process');
            xlabel(app.UIAxes,'Frequency (f)');
            ylabel(app.UIAxes,'Power/ frequency Sx (f)');
            %app.UIAxes.XLabel="";
            %app.UIAxes.YLabel=")";
            
        end

        % Button pushed function: 
        % CalculatethetotalaveragepowerButton
        function CalculatethetotalaveragepowerButtonPushed(app, event)
            [R C]=size(app.X);
            for v=1:R
                tvp(v)=sum(app.X(v,:).*app.X(v,:));
            end
            pAVG=mean(tvp);
            str= "The Total Average Power of the process is  " + pAVG+ "    ";
            
            f = msgbox(str,"Total Average Power",'none');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ANALYSISOFSTOCHASTICPROCESSESLabel = uilabel(app.UIFigure);
            app.ANALYSISOFSTOCHASTICPROCESSESLabel.Text = 'ANALYSIS OF STOCHASTIC PROCESSES';
            app.SamplefunctionstodisplayEditFieldLabel = uilabel(app.UIFigure);
            app.SamplefunctionstodisplayEditFieldLabel.Text = '# Sample functions to display';
            app.SamplefunctionstodisplayEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplefunctionstodisplayEditField.ValueChangedFcn = createCallbackFcn(app, @SamplefunctionstodisplayEditFieldValueChanged, true);
            app.CalculateandplottheensemblemeanButton = uibutton(app.UIFigure, 'push');
            app.CalculateandplottheensemblemeanButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandplottheensemblemeanButtonPushed, true);
            app.CalculateandplottheensemblemeanButton.Text = ' Calculate and plot the ensemble mean';
            app.CalculateandplotthestatisticalautocorrelationButton = uibutton(app.UIFigure, 'push');
            app.CalculateandplotthestatisticalautocorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandplotthestatisticalautocorrelationButtonPushed, true);
            app.CalculateandplotthestatisticalautocorrelationButton.Text = 'Calculate and plot the statistical auto-correlation';
            app.CalculatethetimeautocorrelationfunctionButton = uibutton(app.UIFigure, 'push');
            app.CalculatethetimeautocorrelationfunctionButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatethetimeautocorrelationfunctionButtonPushed, true);
            app.CalculatethetimeautocorrelationfunctionButton.Text = 'Calculate the time auto-correlation function';
            app.CalculateandplotthepowerspectraldensityButton = uibutton(app.UIFigure, 'push');
            app.CalculateandplotthepowerspectraldensityButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandplotthepowerspectraldensityButtonPushed, true);
            app.CalculateandplotthepowerspectraldensityButton.Text = 'Calculate and plot the power spectral density';
            app.CalculatethetotalaveragepowerButton = uibutton(app.UIFigure, 'push');
            app.CalculatethetotalaveragepowerButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatethetotalaveragepowerButtonPushed, true);
            app.CalculatethetotalaveragepowerButton.Text = 'Calculate the total average power';
            app.ofsamplefunctionsiandjEditFieldLabel = uilabel(app.UIFigure);
            app.ofsamplefunctionsiandjEditFieldLabel.Text = '# of sample functions i and j';
            app.ofsamplefunctionsiandjEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofsamplefunctionsiandjEditField.ValueChangedFcn = createCallbackFcn(app, @ofsamplefunctionsiandjEditFieldValueChanged, true);
            app.CalculatethetimemeanButton = uibutton(app.UIFigure, 'push');
            app.CalculatethetimemeanButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatethetimemeanButtonPushed, true);
            app.CalculatethetimemeanButton.Text = 'Calculate the time mean';
            app.ofsamplefunctioniEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ofsamplefunctioniEditField_2.ValueChangedFcn = createCallbackFcn(app, @ofsamplefunctioniEditField_2ValueChanged, true);
            app.ofsamplefunctionEditFieldLabel = uilabel(app.UIFigure);
            app.ofsamplefunctionEditFieldLabel.Text = '# of sample function ';
            app.ofsamplefunctionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ofsamplefunctionEditField.ValueChangedFcn = createCallbackFcn(app, @ofsamplefunctionEditFieldValueChanged, true);
            app.ofsamplefunctionEditField_2Label = uilabel(app.UIFigure);
            app.ofsamplefunctionEditField_2Label.Text = '# of sample function ';
            app.ofsamplefunctionEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ofsamplefunctionEditField_2.ValueChangedFcn = createCallbackFcn(app, @ofsamplefunctionEditField_2ValueChanged, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end